classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        QLearningButton            matlab.ui.control.Button
        StartpointxEditFieldLabel  matlab.ui.control.Label
        point1                     matlab.ui.control.NumericEditField
        GridWorldEditFieldLabel    matlab.ui.control.Label
        grid                       matlab.ui.control.EditField
        startpointyEditFieldLabel  matlab.ui.control.Label
        point2                     matlab.ui.control.NumericEditField
        QLearningMethodButton      matlab.ui.control.Button
        AMethodButton              matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: QLearningButton
        function QLearningButtonPushed(app, event)
            
            model = xlsread(app.grid.Value);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for x= 0:9
    for y = 0:9
        
        rectangle('Position',[x y 1 1],'FaceColor',[.5 .5 .5],'EdgeColor',[.192,.192,.192] )
        if model(9-y+1,x+1) == 0
        rectangle('Position',[x y 1 1],'FaceColor',[1 1 1],'EdgeColor',[.192,.192,.192] )
        end
        if model(9-y+1,x+1) == -1
        rectangle('Position',[x y 1 1],'FaceColor',[139/255,69/255,19/255] ,'EdgeColor',[.192,.192,.192])
        end
    end
    rectangle('Position',[8 1 1 1],'FaceColor','r' ,'EdgeColor',[.192,.192,.192])
    t = text(8.1, 1.5, 'Goal')
    t.FontSize = 10;                     % make the text larger
    t.FontWeight = 'bold';
               
            end
            pause(1)
            
            global Q1
            global Q2
            global Q3
            global Q4
            global Qtable
            % initial Q tables for up, right, down, left
            Q1 = zeros(size(model)); %up
            Q2 = zeros(size(model)); %right
            Q3 = zeros(size(model)); %down
            Q4 = zeros(size(model)); %left
            Qtable = zeros(size(model));
            % parameters for updating Q
            gamma = 0.8;
            alpha = 0.9;
            epsilon = 0.5;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%updating Q%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            K = 10000; % number of the episodes to update the Q
            for i = 1:K
    
            %%%%%%%%%%%%%%%%% 0ne episode %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            s = 1 ; % number of the steps in each iteration 
            
            % for each episode select a random start point
            currentState = [randi([1 10],1,1) randi([1 10],1,1)]; 
            if model( currentState(1) , currentState(2) ) == -1
    currentState = [2 2];
            end
            
            while s <= 150 % 150 is the max number of steps in each episode
            s = s+1;
            if model( currentState(1) , currentState(2) ) == 100 % agent has reached to the goal
    break
            end 
            % update all the four Qs in the current state 
            a = currentState(1);
            b = currentState(2);
            
            % select action based on the epsilon policy to find the next state 
            r = rand;
            r2 = rand;
            QState = [Q1(a,b); Q2(a,b); Q3(a,b); Q4(a,b)];
            action = nextAction(epsilon, QState, r, r2);
            
            if action == 1
            % update Q1
            [NS1,NS2]  = nextStep(a,b, 1, 1);
            QNS = [Q1(NS1,NS2) ; Q2(NS1,NS2) ; Q3(NS1,NS2) ; Q4(NS1,NS2) ];
            Q1(a,b) = Q1(a,b) + alpha*( model(NS1,NS2) + gamma* (max(QNS)) - Q1(a,b));
            elseif action == 2
            % update Q2
            [NS1,NS2]  = nextStep(a,b, 2, 1);
            QNS = [Q1(NS1,NS2) ; Q2(NS1,NS2) ; Q3(NS1,NS2) ; Q4(NS1,NS2) ];
            Q2(a,b) = Q2(a,b) + alpha*( model(NS1,NS2) + gamma* (max(QNS)) - Q2(a,b));
            elseif action == 3
            % update Q3
            [NS1,NS2] = nextStep(a,b, 3, 1);
            QNS = [Q1(NS1,NS2) ; Q2(NS1,NS2) ; Q3(NS1,NS2) ; Q4(NS1,NS2) ];
            Q3(a,b) = Q3(a,b) + alpha*( model(NS1,NS2) + gamma* (max(QNS)) - Q3(a,b));
            elseif action == 4
            % update Q4 
            [NS1,NS2]  = nextStep(a,b, 4, 1);
            QNS = [Q1(NS1,NS2) ; Q2(NS1,NS2) ; Q3(NS1,NS2) ; Q4(NS1,NS2) ];
            Q4(a,b) = Q4(a,b) + alpha*( model(NS1,NS2) + gamma* (max(QNS)) - Q4(a,b));
            end
            
            Qtable(a,b) = max([Q1(a,b),Q2(a,b),Q3(a,b),Q4(a,b)]);
            
            % update the next position of the agent
            if model(NS1,NS2)~= -1
            currentState = [NS1, NS2];
            end
            
            end % end of one episode
            end % end of the 1000 episodes of training 
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for x= 0:9
    for y = 0:9
        
        rectangle('Position',[x y 1 1],'FaceColor',[.5 .5 .5],'EdgeColor',[.192,.192,.192] )
        if model(9-y+1,x+1) == 0
        rectangle('Position',[x y 1 1],'FaceColor',[1 1 1],'EdgeColor',[.192,.192,.192] )
        t = text(x+.5,y+.9, num2str(round(Q1(9-y+1,x+1))))
        t.FontSize = 7;                     
        t.FontWeight = 'bold';
        t = text(x+.7,y+.5, num2str(round(Q2(9-y+1,x+1))))
        t.FontSize = 7;                     
        t.FontWeight = 'bold';
        t = text(x+.5,y+.1, num2str(round(Q3(9-y+1,x+1))))
        t.FontSize = 7;                      
        t.FontWeight = 'bold';
        t = text(x+.1,y+.5, num2str(round(Q4(9-y+1,x+1))))
        t.FontSize = 7;                      
        t.FontWeight = 'bold';
        end
        if model(9-y+1,x+1) == -1
        rectangle('Position',[x y 1 1],'FaceColor',[139/255,69/255,19/255] ,'EdgeColor',[.192,.192,.192])
        end
    end
    rectangle('Position',[8 1 1 1],'FaceColor','r' ,'EdgeColor',[.192,.192,.192])
    t = text(8.1, 1.5, 'Goal')
    t.FontSize = 10;                     % make the text larger
    t.FontWeight = 'bold';
               
            end
            
        end

        % Button pushed function: QLearningMethodButton
        function QLearningMethodButtonPushed(app, event)
            global Q1
            global Q2
            global Q3
            global Q4
            model = xlsread(app.grid.Value);
             
             for x= 0:9
     for y = 0:9
        
        rectangle('Position',[x y 1 1],'FaceColor',[.5 .5 .5],'EdgeColor',[.192,.192,.192] )
         if model(9-y+1,x+1) == 0
         rectangle('Position',[x y 1 1],'FaceColor',[1 1 1],'EdgeColor',[.192,.192,.192] )
         t = text(x+.5,y+.9, num2str(round(Q1(9-y+1,x+1))))
         t.FontSize = 7;                     
         t.FontWeight = 'bold';
         t = text(x+.7,y+.5, num2str(round(Q2(9-y+1,x+1))))
         t.FontSize = 7;                     
         t.FontWeight = 'bold';
         t = text(x+.5,y+.1, num2str(round(Q3(9-y+1,x+1))))
         t.FontSize = 7;                      
         t.FontWeight = 'bold';
         t = text(x+.1,y+.5, num2str(round(Q4(9-y+1,x+1))))
         t.FontSize = 7;                      
         t.FontWeight = 'bold';
         end
         if model(9-y+1,x+1) == -1
         rectangle('Position',[x y 1 1],'FaceColor',[139/255,69/255,19/255] ,'EdgeColor',[.192,.192,.192])
         end
     end
     rectangle('Position',[8 1 1 1],'FaceColor','r' ,'EdgeColor',[.192,.192,.192])
     t = text(8.1, 1.5, 'Goal')
     t.FontSize = 10;                     % make the text larger
     t.FontWeight = 'bold';
             end
            
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%find the path 
              
            
             a = app.point1.Value;
             b = app.point2.Value;
             path = [a b];
             while model(a,b)~= 100
             
             QState = [Q1(a,b); Q2(a,b); Q3(a,b); Q4(a,b)];
             movementOrder =  find(QState == max(QState(:)),1, 'first');
             [NS1,NS2]  = nextStep(a,b, movementOrder, 2);
             if model(NS1,NS2)~= -1
             current = [NS1, NS2];
             a = current(1);
             b = current(2);
             path = [path ; a,b];
             end
            
             end
             %%%%%%%%%%%%%%%%%%%%%%%%%% show the path 
            
             % construct the path
             s = size(path);
             
             for i = 1: s(1)-1
     point = path(i,:);
     a= point(1);
     b = point(2);
     current = [b-1, 10-a];
     point = path(i+1,:);
     a= point(1);
     b = point(2);
     next = [b-1, 10-a];
     line([current(1)+.5 next(1)+.5],[current(2)+.5 next(2)+.5],'Color','r','LineWidth',4)
     pause(0.3)
             end
             
        end

        % Button pushed function: AMethodButton
        function AMethodButtonPushed(app, event)
             
            model = xlsread(app.grid.Value);
             
            start = [2 2];
            goal = [9 9];
            
            g = zeros(size(model));
            for i =1 : 10
    for j = 1:10
              g(i,j) = pdist([[i,j] ; goal],'euclidean');  
    end
            end
            
            h = 1000 * ones(size(model));
            f = zeros(size(model));
            parent = zeros(size(model));
            info = model; %10 if close set, 5 if openset, -1 if blocked, 0 normal, 100 goal
            
            
            h(start(1), start(2)) = 0;
            f(start(1), start(2)) = 0 + g(start(1), start(2));
            openSetF = f(start(1), start(2));
            openSetIndex1= start(1);
            openSetIndex2= start(2);
             
            
            a = find(openSetF == min(openSetF(:)),1, 'first'); % find the min in openSet
            current = [openSetIndex1(a) , openSetIndex2(a)];
            
            %count = 0
            
            while model(current(1), current(2)) ~= 100
    
            %count = count +1
            f(current(1), current(2)) = g(current(1), current(2)) + h(current(1), current(2));
            info(current(1), current(2)) = 10; % set it as a closeSet
            openSetF (a ) = 10000000000; % to not be found again 
            hNew= h(current(1), current(2)) + 1;
            
            
            neighborUp  = current + [-1 , 0] ;
            if (info(neighborUp(1), neighborUp(2)) ~= -1) && (info(neighborUp(1), neighborUp(2)) ~= 10) % if it is not blocked or closedSet
             
    % calculate the h and the f
    if hNew < h(neighborUp(1), neighborUp(2))
        h(neighborUp(1), neighborUp(2)) = hNew;
        parent(neighborUp(1), neighborUp(2)) = 3; % parent state is down 
        f(neighborUp(1), neighborUp(2)) = hNew + g(neighborUp(1), neighborUp(2));
        
    end  
    info(neighborUp(1), neighborUp(2)) = 5; % set as openSet
    openSetF = [openSetF ;f(neighborUp(1), neighborUp(2)) ];
    openSetIndex1 = [openSetIndex1 ; neighborUp(1)] ;
    openSetIndex2 = [openSetIndex2 ; neighborUp(2)] ;
            end    
            
            neighborRight  = current + [0 , +1] ;
            if (info(neighborRight(1), neighborRight(2)) ~= -1) && (info(neighborRight(1), neighborRight(2)) ~= 10) % if it is not blocked or closedSet
        
    % calculate the h and the f
    if hNew < h(neighborRight(1), neighborRight(2))
        h(neighborRight(1), neighborRight(2)) = hNew;
        parent(neighborRight(1), neighborRight(2)) = 4; % parent state is down
        f(neighborRight(1), neighborRight(2)) = hNew + g(neighborRight(1), neighborRight(2));
        
    end  
    info(neighborRight(1), neighborRight(2)) = 5; % set as openSet
    openSetF = [openSetF ;f(neighborRight(1), neighborRight(2)) ];
    openSetIndex1 = [openSetIndex1 ; neighborRight(1)] ;
    openSetIndex2 = [openSetIndex2 ; neighborRight(2)] ;
            end 
            
            neighborDown  = current + [+1 , 0] ;
            if (info(neighborDown(1), neighborDown(2)) ~= -1) && (info(neighborDown(1), neighborDown(2)) ~= 10) % if it is not blocked or closedSet
    
    % calculate the h and the f
    if hNew < h(neighborDown(1), neighborDown(2))
        h(neighborDown(1), neighborDown(2)) = hNew;
        parent(neighborDown(1), neighborDown(2)) = 1; % parent state is down 
        f(neighborDown(1), neighborDown(2)) = hNew + g(neighborDown(1), neighborDown(2));
         
    end 
    info(neighborDown(1), neighborDown(2)) = 5; % set as openSet
    openSetF = [openSetF ;f(neighborDown(1), neighborDown(2)) ];
    openSetIndex1 = [openSetIndex1 ; neighborDown(1)] ;
    openSetIndex2 = [openSetIndex2 ; neighborDown(2)] ;
            end 
            
            neighborLeft = current + [0 , -1] ;
            if (info(neighborLeft(1), neighborLeft(2)) ~= -1) && (info(neighborLeft(1), neighborLeft(2)) ~= 10) % if it is not blocked or closedSet
               
    % calculate the h and the f
    if hNew < h(neighborLeft(1), neighborLeft(2))
        h(neighborLeft(1), neighborLeft(2)) = hNew;
        parent(neighborLeft(1), neighborLeft(2)) = 2; % parent state is down 
        f(neighborLeft(1), neighborLeft(2)) = hNew + g(neighborLeft(1), neighborLeft(2));
         
    end   
    info(neighborLeft(1), neighborLeft(2)) = 5; % set as openSet
    openSetF = [openSetF ;f(neighborLeft(1), neighborLeft(2)) ];
    openSetIndex1 = [openSetIndex1 ; neighborLeft(1)] ;
    openSetIndex2 = [openSetIndex2 ; neighborLeft(2)] ;
            end 
            
             
            a = find(openSetF == min(openSetF(:)),1, 'first'); % find the min in openSet
            current = [openSetIndex1(a) , openSetIndex2(a)];
            
            end
            
            current = goal;
            a = current(1);
            b = current(2);
            path = current;
            while (a~=2) && (b~=2)
            if parent(a,b) == 1 
    next1 = a -1;
    next2 = b;
            elseif parent(a,b) == 2 
    next1 = a;
    next2 = b+1;
            elseif parent(a,b) == 3
    next1 = a+1;
    next2 = b;
            elseif parent(a,b) == 4
    next1 = a;
    next2 = b-1;
            end
             
            path = [path ; next1 next2];
            
            a = next1;
            b = next2;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%visualize%%%%%%%%%%%%%%%%%%%%%%%%%
            for x= 0:9
    for y = 0:9
        
        rectangle('Position',[x y 1 1],'FaceColor',[.5 .5 .5],'EdgeColor',[.192,.192,.192] )
        if model(9-y+1,x+1) == 0
            rectangle('Position',[x y 1 1],'FaceColor',[1 1 1],'EdgeColor',[.192,.192,.192] )
        end
        if info(9-y+1,x+1) == 5 % openSet
            rectangle('Position',[x y 1 1],'FaceColor','b','EdgeColor',[.192,.192,.192] )
            t = text(x+.05,y+.7, strcat('g=',num2str( h(9-y+1,x+1))) )
            t.FontSize = 7;                     
             
            t = text(x+.05,y+.3, strcat('h=',num2str( g(9-y+1,x+1))) )
            t.FontSize = 7;                     
             
        end
        if info(9-y+1,x+1) == 10 % closeSet
            rectangle('Position',[x y 1 1],'FaceColor','g','EdgeColor',[.192,.192,.192] )
             t = text(x+.05,y+.7, strcat('g=',num2str( h(9-y+1,x+1))) )
            t.FontSize = 7;                     
             
            t = text(x+.05,y+.3, strcat('h=',num2str( g(9-y+1,x+1))) )
            t.FontSize = 7;                     
             
        end
        
        if model(9-y+1,x+1) == -1
           rectangle('Position',[x y 1 1],'FaceColor',[139/255,69/255,19/255] ,'EdgeColor',[.192,.192,.192])
        end
            
    end
            end
    rectangle('Position',[8 1 1 1],'FaceColor','r' ,'EdgeColor',[.192,.192,.192])
    t = text(8.1, 1.5, 'Goal')
    t.FontSize = 10;                     % make the text larger
    t.FontWeight = 'bold';
            
             
            % construct the path
            s = size(path);
            point = path(s(1),:);
            a= point(1);
            b = point(2);
            line([1+.5 b-1+.5],[8+.5 10-a+.5],'Color','r','LineWidth',4)
            for i = 1:s(1)-1
    point = path(s(1)-i+1,:);
    a= point(1);
    b = point(2);
    current = [b-1, 10-a];
    point = path(s(1)-i,:);
    a= point(1);
    b = point(2);
    next = [b-1, 10-a];
    line([current(1)+.5 next(1)+.5],[current(2)+.5 next(2)+.5],'Color','r','LineWidth',4)
    pause(0.3)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.QLearningButton = uibutton(app.UIFigure, 'push');
            app.QLearningButton.ButtonPushedFcn = createCallbackFcn(app, @QLearningButtonPushed, true);
            app.QLearningButton.Text = 'Q-Learning';
            app.StartpointxEditFieldLabel = uilabel(app.UIFigure);
            app.StartpointxEditFieldLabel.Text = 'Start point x';
            app.point1 = uieditfield(app.UIFigure, 'numeric');
            app.GridWorldEditFieldLabel = uilabel(app.UIFigure);
            app.GridWorldEditFieldLabel.Text = 'Grid World';
            app.grid = uieditfield(app.UIFigure, 'text');
            app.grid.Value = 'model1';
            app.startpointyEditFieldLabel = uilabel(app.UIFigure);
            app.startpointyEditFieldLabel.Text = 'start point y';
            app.point2 = uieditfield(app.UIFigure, 'numeric');
            app.QLearningMethodButton = uibutton(app.UIFigure, 'push');
            app.QLearningMethodButton.ButtonPushedFcn = createCallbackFcn(app, @QLearningMethodButtonPushed, true);
            app.QLearningMethodButton.Text = 'Q-Learning Method';
            app.AMethodButton = uibutton(app.UIFigure, 'push');
            app.AMethodButton.ButtonPushedFcn = createCallbackFcn(app, @AMethodButtonPushed, true);
            app.AMethodButton.Text = 'A* Method';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end